classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Button    matlab.ui.control.StateButton
        DropDown  matlab.ui.control.DropDown
        Label     matlab.ui.control.Label
        UIAxes_2  matlab.ui.control.UIAxes
        UIAxes    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        s % Description
    end
    
    methods (Access = private)
        
        function results = plot_curve(app, src, evt)
            crc = read(src, 1,"single");
            data = read(src, 1000,"single");

            if crc ~= app.crc16(data)
                msgbox("数据传输错误")
                return
            end
            
            plot(data, 'Parent', app.UIAxes)
            [normamp, freqnorm] = findFFT(data,'-sampFreq',1000, '-window', 'hann');
            plot(freqnorm,normamp,'Parent',app.UIAxes_2);
        end
        
        function [crc, hex]  = crc16(app, packet)
            crc = 0;
            % crc = hex2dec('FFFF');   % for 0xFFFF version
            packet = uint16(packet);
            for i = 1:length(packet)
                crc = bitxor( crc, bitshift(packet(i),8) );
                for bit = 1:8
                    if bitand( crc, hex2dec('8000') )     % if MSB=1
                      crc = bitxor( bitshift(crc,1), hex2dec('1021') );
                    else
                      crc = bitshift(crc,1);
                    end
                    crc = bitand( crc, hex2dec('ffff') );  % trim to 16 bits
                end
            end
            hex = dec2hex(crc);

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.DropDown.Items = serialportlist;

        end

        % Value changed function: Button
        function ButtonValueChanged(app, event)
            value = app.Button.Value;
            app.s = 0;  % 清空串口
            if value
                app.s = serialport(app.DropDown.Value, 115200,"Timeout",5);
                configureCallback(app.s, "terminator",@app.plot_curve);
                msgbox("串口打开成功")
            else
                app.s = 0;  % 清空串口
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '原始数据')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, '频谱图')
            zlabel(app.UIAxes_2, 'Z')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '串口';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {};
            app.DropDown.Value = {};
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Text = '打开串口';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end