classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        ButtonGroup_2    matlab.ui.container.ButtonGroup
        HSVButton        matlab.ui.control.RadioButton
        RGBButton        matlab.ui.control.RadioButton
        LabButton        matlab.ui.control.RadioButton
        Spinner          matlab.ui.control.Spinner
        Label            matlab.ui.control.Label
        Button_6         matlab.ui.control.Button
        Button_5         matlab.ui.control.Button
        Button_4         matlab.ui.control.Button
        Button_3         matlab.ui.control.Button
        Button_2         matlab.ui.control.Button
        Button           matlab.ui.control.Button
        ButtonGroup      matlab.ui.container.ButtonGroup
        prewittButton    matlab.ui.control.RadioButton
        sobelButton      matlab.ui.control.RadioButton
        canny_oldButton  matlab.ui.control.RadioButton
        cannyButton      matlab.ui.control.RadioButton
        UIAxes_5         matlab.ui.control.UIAxes
        UIAxes_4         matlab.ui.control.UIAxes
        UIAxes_3         matlab.ui.control.UIAxes
        UIAxes_2         matlab.ui.control.UIAxes
        UIAxes           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        src % 原始图
        img_e % 边缘
        BW_c % 边缘连接
        BW_label % 色彩图
        color_block % 色彩块
        BW_edge % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            [file,path,indx] = uigetfile("*.*", "选择一个图片");
            if isequal(file,0)
               disp('User selected Cancel')
            else
               disp(['User selected ', fullfile(path, file),... 
                     ' and filter index: ', num2str(indx)])
            end
            app.src = imread(fullfile(path, file));
            imshow(app.src, "Parent", app.UIAxes)
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            img_gray = im2gray(app.src);
            app.img_e = edge(img_gray,app.ButtonGroup.SelectedObject.Text);
            app.img_e = bwareaopen(app.img_e,20,8); % 删除面积小于20的区域
            imshow(app.img_e, "Parent", app.UIAxes_2)
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.BW_c = bw_connect(app.img_e);
            imshow(app.BW_c, "Parent", app.UIAxes_3)

            function BW = bw_connect(BW)
            % 获取连通域的属性
            [h, w] = size(BW);
            BW(1,:) = 1;BW(:,1) = 1;BW(h,:) = 1;BW(:,w) = 1;  % 将图片最外全置为1
            stats = regionprops(BW, 'PixelList'); % 获取连通域的每个点
            img_l = bwlabel(BW, 8); % 连通域标记
            r = 10; % 连接查找半径
            
            
                % 循环遍历每个连通域
                for i = 1:length(stats)
                    breakpoints = zeros(0); % 断点位置集合
                    % 获取当前连通域的像素坐标
                    pixelList = stats(i).PixelList;
                     pixelList = pixelList(:, [2,1]);
                    
                    % 去除边点
                    pixelList(pixelList(:,1)==1, :) = [];
                    pixelList(pixelList(:,2)==1, :) = [];
                    pixelList(pixelList(:,1)==h, :) = [];
                    pixelList(pixelList(:,2)==w, :) = [];
                
                    ind = sub2ind(size(BW), pixelList(:,1), pixelList(:,2));
                    tp = zeros(size(BW));
                    tp(ind) = 1;
            
                    for j = 1:length(pixelList)
                        x = pixelList(j, 1);
                        y = pixelList(j, 2);
                        if sum(BW(x-1:x+1, y-1:y+1), 'all') <= 2
                            breakpoints = [breakpoints; [x, y]];
                        end
                    end
            
            %         breakpoints = sort(breakpoints, 1);
                    visit_p = zeros(0);
                    for bp = 1:height(breakpoints)-1
                        breakpoint = breakpoints(bp:bp+1, :);
                    % 连接距离小于100的断点
                        if pdist(breakpoint) < 10
                            BW = point_connect(breakpoint, BW);
                            BW = point_connect(flipud(breakpoint), BW);
                            visit_p = [visit_p; bp; bp+1];
                        end
                    end
                    % 用不同半径去查找最近点
                    breakpoints(visit_p,:)=[];
                    for k = 1:height(breakpoints)
                        label_n = img_l(breakpoints(k, 1), breakpoints(k, 2));
                        tp = BW;
                        tp(img_l == label_n) = 0;
                        tp(1,:) = 1;tp(:,1) = 1;tp(h,:) = 1;tp(:,w) = 1;  % 将图片最外全置为1
            
                        for r_ =1:r
                            m = tp(breakpoints(k, 1)-r_:breakpoints(k, 1)+r_, breakpoints(k, 2)-r_:breakpoints(k, 2)+r_);
                            if sum(m, 'all') > 0
                                p = find(m);
                                [p1, p2] = ind2sub([2*r_+1,2*r_+1], p(1));
                                p = [breakpoints(k, 1)-r_-1+p1, breakpoints(k, 2)-r_-1+p2];
                                BW = point_connect([breakpoints(k,:); p], BW);
%                                 BW = point_connect([p; breakpoints(k,:)], BW);
                                break
                            end
                        end
                    end
            
                end
            end
            
            %% 连接两个点
            function BW = point_connect(breakpoint, BW)
            
                x = breakpoint(2,1) - breakpoint(1,1);
                y = breakpoint(2,2) - breakpoint(1,2);
                if abs(x)>abs(y)
                    for k =1:abs(x)
                        % 连接两个点
                        if x > 0
                            ind = sub2ind(size(BW), breakpoint(1,1)+k, breakpoint(1,2)+round(y/x));
                        else
                            ind = sub2ind(size(BW), breakpoint(1,1)-k, breakpoint(1,2)-round(y/x));
                        end
                        BW(ind) = 1;
                    end
                else
                    for k =1:abs(y)
                        if y > 0
                        % 连接两个点
                            ind = sub2ind(size(BW), breakpoint(1,1)+round(x/y), breakpoint(1,2)+k);
                        else
                            ind =  sub2ind(size(BW), breakpoint(1,1)-round(x/y), breakpoint(1,2)-k);
                        end
                        BW(ind) = 1;
                    end
                end
            end
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.BW_edge = bwareaopen(1-app.BW_c,40,4); % 删除面积小于40的区域
            app.BW_label = bwlabel(app.BW_edge, 4);
            imagesc(app.BW_label, 'Parent', app.UIAxes_4)
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            numColors = app.Spinner.Value;
            if app.ButtonGroup_2.SelectedObject.Text == "L*a*b"
                img_rgb = im2single(rgb2lab(app.src));   % lab色域
            elseif app.ButtonGroup_2.SelectedObject.Text == "HSV"
                img_rgb = im2single(rgb2hsv(app.src));   % HSV色域
            else
                img_rgb = im2single(app.src);   % rgb色域
            end

            L = imsegkmeans(img_rgb, numColors);  % kmeans分割图像
            app.color_block = zeros(0);
            cla(app.UIAxes_5)
            for c = 1:app.Spinner.Value
                r = app.src(:,:,1);
                g = app.src(:,:,2);
                b = app.src(:,:,3);
                i = find(L==c);
                app.color_block = [app.color_block; mean(r(i)),mean(g(i)),mean(b(i))];
                rectangle(app.UIAxes_5,'Position',[c-0.5,0,1,1],'FaceColor',double(app.color_block(end,:))/255,'LineWidth',1)
            end
            xlim(app.UIAxes_5, [0, numColors+1]);
            xticks(app.UIAxes_5, 0:1:numColors)
            yticks(app.UIAxes_5, [0,1])
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            BW = app.BW_label;
            labels = unique(BW);

            r = app.src(:,:,1);
            g = app.src(:,:,2);
            b = app.src(:,:,3);
            figure
            imshow(app.BW_edge) % 显示轮廓图
            for l = 1:length(labels)-1

                i = find(BW==l);
                [x, y] = ind2sub(size(BW), i(round(length(i)/2)));
                find_c = [median(r(i)),median(g(i)),median(b(i))];

                c_distance = pdist2(app.color_block, find_c);  % 计算最接近的颜色
                [d, c_i] = min(c_distance);
                t = text(y,x, string(c_i));  % 添加数字
                t.FontSize = 9;
                t.Color = 'red';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '原始图片')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, '边缘提取')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, '边缘连接')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, '涂色区域分割')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, '颜色卡')
            zlabel(app.UIAxes_5, 'Z')
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.Title = '边缘提取';
            app.cannyButton = uiradiobutton(app.ButtonGroup);
            app.cannyButton.Text = 'canny';
            app.canny_oldButton = uiradiobutton(app.ButtonGroup);
            app.canny_oldButton.Text = 'canny_old';
            app.canny_oldButton.Value = true;
            app.sobelButton = uiradiobutton(app.ButtonGroup);
            app.sobelButton.Text = 'sobel';
            app.prewittButton = uiradiobutton(app.ButtonGroup);
            app.prewittButton.Text = 'prewitt';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '载入图片';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '边缘提取';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '边缘连接';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '色彩分割';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '生成最终图';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '色彩聚类';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '颜色数量';
            app.Spinner = uispinner(app.UIFigure);
            app.Spinner.Value = 9;
            app.ButtonGroup_2 = uibuttongroup(app.UIFigure);
            app.ButtonGroup_2.Title = '色域';
            app.LabButton = uiradiobutton(app.ButtonGroup_2);
            app.LabButton.Text = 'L*a*b';
            app.RGBButton = uiradiobutton(app.ButtonGroup_2);
            app.RGBButton.Text = 'RGB';
            app.RGBButton.Value = true;
            app.HSVButton = uiradiobutton(app.ButtonGroup_2);
            app.HSVButton.Text = 'HSV';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end